classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ppnafilButton   matlab.ui.control.Button
        SparafilButton  matlab.ui.control.Button
        SpelainButton   matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dq
        data
    end
    
    methods (Access = private)
        
        function Init(app)
            app.dq = daq("ni");
            app.dq.Rate = 10;
            addinput(app.dq, "Dev2", "ai0", "Voltage");
            %start(app.dq, "NumScans",5);
        end
        
        function UpdateData(app)
            %while true
                app.data = [app.data; read(app.dq, seconds(1))];
                plot(app.UIAxes, app.data.Time, app.data.Variables);
            %end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Init(app);
            %UpdateData(app);
        end

        % Callback function
        function TestEditFieldValueChanged(app, event)
            value = app.TestEditField.Value;
            %app.UIAxes.XLim
            set(app.UIAxes, "XLim", [0 value]);
        end

        % Button pushed function: SpelainButton
        function SpelainButtonPushed(app, event)
            UpdateData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spänningsdiagram')
            xlabel(app.UIAxes, 'Tid (s)')
            ylabel(app.UIAxes, 'Spänning (V)')
            zlabel(app.UIAxes, 'Z')
            app.SpelainButton = uibutton(app.UIFigure, 'push');
            app.SpelainButton.ButtonPushedFcn = createCallbackFcn(app, @SpelainButtonPushed, true);
            app.SpelainButton.Text = 'Spela in';
            app.SparafilButton = uibutton(app.UIFigure, 'push');
            app.SparafilButton.Text = 'Spara fil';
            app.ppnafilButton = uibutton(app.UIFigure, 'push');
            app.ppnafilButton.Text = 'Öppna fil';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end